classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        levelEditField            matlab.ui.control.NumericEditField
        levelEditFieldLabel       matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        simulationspeedKnob       matlab.ui.control.Knob
        simulationspeedKnobLabel  matlab.ui.control.Label
        BallNum                   matlab.ui.control.NumericEditField
        BallNumEditFieldLabel     matlab.ui.control.Label
        simulation                matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        Lamp                      matlab.ui.control.Lamp
        LampLabel                 matlab.ui.control.Label
        Switch                    matlab.ui.control.Switch
        UIAxes                    matlab.ui.control.UIAxes
        Toolbar                   matlab.ui.container.Toolbar
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: simulation
        function simulationPushed(app, event)
N = app.BallNum.Value;
level = app.levelEditField.Value;
fig=figure();
screenSize=get(0,'ScreenSize');
fig.Position=[screenSize(1,[3,4])./10,...
screenSize(4).*0.5,screenSize(4).*0.8];
fig.Name='galtonBySlandarer';
fig.NumberTitle='off';
fig.Resize='off';
fig.MenuBar='none';
ax=axes(fig);
ax.Position=[0 0 1 1];
hold(ax,'on');
ax.XLim=[-6.5 6.5];
ax.YLim=[0 20.5];
ax.XTick=[];
ax.YTick=[];
ax.XColor='none';
ax.YColor='none';
ax.Color=[.1 .1 .1];
plot([1;1]*(-5.5:1:5.5),[0;8].*ones(2,12),'LineWidth',2.5,'Color',[180,64,71]./255)
scatterSet=[];
for k=1:level
    tX=(1:k)-(1+k)/2;
    tY=(20-k).*ones(1,k);
    scatterSet=[scatterSet;[tX',tY']];
end
scatter(scatterSet(:,1),scatterSet(:,2),100,'filled','CData',[140,196,123]./255);
plot([-6.5,-6.5,-1.5,-1,-1,-6.5,-6.5,6.5,6.5,1,1,1.5,6.5,6.5],...
     [8,20.5,20.5,19.8,19,8,0,0,8,19,19.8,20.5,20.5,8],...
     'LineWidth',4,'Color',[140,196,123]./255);
scatter(0,20,100,'filled','LineWidth',2,'CData',[255,255,0]./255)
accHdl=scatter([],[],100,'filled','LineWidth',2,'CData',[255,255,0]./255);
pathHdl=plot(-10,-10,'LineWidth',2,'Color',[181,200,219]./255);
arrowHdl=text(-10,7.2,'↓','HorizontalAlignment','center','FontSize',40,'Color',[181,200,219]./255);
kerHdl=plot(-10,-10,'LineWidth',3,'Color',[0,0,255]./255);

X=-6:6;Y=zeros(1,13);ballSet=[];
for n=1:N
    rightnowposition=[0,19.5];route=[0,20;rightnowposition];
    transformposition=randi(2,[1,12])-1.5;
    for i=1:length(transformposition)
        rightnowposition=rightnowposition+[transformposition(i),-0.5];
        route=[route;rightnowposition];
        rightnowposition=rightnowposition+[0,-0.5];
        route=[route;rightnowposition];
    end
    arrowHdl.Position(1)=rightnowposition(1);
    Y=Y+(rightnowposition(1)==X);
    ballSet=[ballSet;rightnowposition(1),Y(rightnowposition(1)==X).*0.3];
    accHdl.XData=ballSet(:,1);
    accHdl.YData=ballSet(:,2);
    [yi,xi]=ksdensity(ballSet(:,1));
    kerHdl.XData=xi;
    kerHdl.YData=yi.*n.*0.3;
    drawnow
    pause(app.simulationspeedKnob.Value)
end

 disp(ballSet(:,1)')
 disp(ballSet(:,2)')
    saveas(fig,'fmyfig.jpeg');
    imshow('fmyfig.jpeg','parent',app.UIAxes);


        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,"On")
                app.Lamp.Color = 'g';
            end
            if strcmp(value,"Off")
                app.Lamp.Color = 'r'; 
            end
        end

        % Callback function
        function Image2Clicked(app, event)
         
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
           
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.simulation = uibutton(app.LeftPanel, 'push');
            app.simulation.ButtonPushedFcn = createCallbackFcn(app, @simulationPushed, true);
            app.simulation.Text = 'simulation';
            app.BallNumEditFieldLabel = uilabel(app.LeftPanel);
            app.BallNumEditFieldLabel.Text = 'BallNum';
            app.BallNum = uieditfield(app.LeftPanel, 'numeric');
            app.simulationspeedKnobLabel = uilabel(app.LeftPanel);
            app.simulationspeedKnobLabel.Text = 'simulation speed';
            app.simulationspeedKnob = uiknob(app.LeftPanel, 'continuous');
            app.simulationspeedKnob.Limits = [0 1];
            app.simulationspeedKnob.MajorTicks = [0 0.25 0.5 0.75 1];
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = '780.jpg';
            app.levelEditFieldLabel = uilabel(app.LeftPanel);
            app.levelEditFieldLabel.Text = 'level';
            app.levelEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'image show')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Switch = uiswitch(app.RightPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.LampLabel = uilabel(app.RightPanel);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.RightPanel);
            app.Toolbar = uitoolbar(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end